classdef HH_propagate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChlorineConductanceCheckBox     matlab.ui.control.CheckBox
        PotassiumConductanceCheckBox    matlab.ui.control.CheckBox
        SodiumConductanceCheckBox       matlab.ui.control.CheckBox
        ChlorineCurrentCheckBox         matlab.ui.control.CheckBox
        PotassiumCurrentCheckBox        matlab.ui.control.CheckBox
        SodiumCurrentCheckBox           matlab.ui.control.CheckBox
        CapacitiveCurrentCheckBox       matlab.ui.control.CheckBox
        StimulationcurrentCheckBox      matlab.ui.control.CheckBox
        MembraneVoltageCheckBox         matlab.ui.control.CheckBox
        AxonlengthEditField             matlab.ui.control.NumericEditField
        AxonlengthEditFieldLabel        matlab.ui.control.Label
        LocationrelativetoaxonEditField  matlab.ui.control.NumericEditField
        LocationrelativetoaxonEditFieldLabel  matlab.ui.control.Label
        LocationrelativetoaxonEditField_2  matlab.ui.control.NumericEditField
        LocationrelativetoaxonEditField_2Label  matlab.ui.control.Label
        StarttimemsEditField            matlab.ui.control.NumericEditField
        StarttimemsEditFieldLabel       matlab.ui.control.Label
        DurationmsEditField             matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel        matlab.ui.control.Label
        MagnitudemuAcm2EditField        matlab.ui.control.NumericEditField
        MagnitudemuAcm2Label            matlab.ui.control.Label
        StarttimemsEditField_2          matlab.ui.control.NumericEditField
        StarttimemsEditField_2Label     matlab.ui.control.Label
        STATUSBARLabel                  matlab.ui.control.Label
        SIMULATEButton                  matlab.ui.control.StateButton
        DurationmsEditField_2           matlab.ui.control.NumericEditField
        DurationmsEditField_2Label      matlab.ui.control.Label
        MagnitudemuAcm2EditField_2      matlab.ui.control.NumericEditField
        MagnitudemuAcm2EditField_2Label  matlab.ui.control.Label
        SimulationTimemsEditField       matlab.ui.control.NumericEditField
        SimulationTimemsEditFieldLabel  matlab.ui.control.Label
        Stimulation2Label               matlab.ui.control.Label
        Stimulation1Label               matlab.ui.control.Label
        PleaseselectvariablestoplotbelowLabel  matlab.ui.control.Label
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SIMULATEButton
        function SIMULATEButtonValueChanged(app, event)
            set(0,'DefaultFigureVisible','off')            
            value = app.SIMULATEButton.Value;
            
            if_v_membrane = app.MembraneVoltageCheckBox.Value;
            if_stim_curr = app.StimulationcurrentCheckBox.Value;
            if_cap_curr = app.CapacitiveCurrentCheckBox.Value;
            if_na_curr = app.SodiumCurrentCheckBox.Value;
            if_k_curr = app.PotassiumCurrentCheckBox.Value;
            if_cl_curr = app.ChlorineCurrentCheckBox.Value;
            if_g_na = app.SodiumConductanceCheckBox.Value;
            if_g_k = app.PotassiumConductanceCheckBox.Value;
            if_g_cl = app.ChlorineConductanceCheckBox.Value;

            selection_idx = [if_v_membrane, if_stim_curr, if_cap_curr, if_na_curr, if_k_curr,if_cl_curr, if_g_na, if_g_k, if_g_cl];

            simulation_time = app.SimulationTimemsEditField.Value;
            axon_length = app.AxonlengthEditField.Value;
            stim1mag = app.MagnitudemuAcm2EditField.Value;
            stim2mag = app.MagnitudemuAcm2EditField_2.Value;
            stim1dur = app.DurationmsEditField.Value;
            stim2dur = app.DurationmsEditField_2.Value;
            stim1start = app.StarttimemsEditField.Value;
            stim2start = app.StarttimemsEditField_2.Value;
            stim1location = app.LocationrelativetoaxonEditField.Value;
            stim2location = app.LocationrelativetoaxonEditField_2.Value;
            
            app.STATUSBARLabel.Text = "Simulation is running";
            app.STATUSBARLabel.BackgroundColor = 'r';
            tic
            [time, V_membrane, I_total, I_s, I_C, I_Na, I_K, I_L, g_Na, g_K, g_L] = HHPropagate(simulation_time, stim1mag, ...
    stim1start, stim1dur, stim1location, stim2mag, ...
    stim2start, stim2dur, stim2location, axon_length, 0);
            time_elapsed = toc;
            app.STATUSBARLabel.Text = ["Simulation is completed." newline ['Elapsed time: ',num2str(time_elapsed),' seconds']];
            app.STATUSBARLabel.BackgroundColor = 'yellow';
            pause(1.0)
            app.STATUSBARLabel.BackgroundColor = 'yellow';
            matrices = {V_membrane,I_s, I_C, I_Na, I_K, I_L, g_Na, g_K, g_L};
            targets = {app.UIAxes, app.UIAxes_2, app.UIAxes_3, app.UIAxes_4, app.UIAxes_5, app.UIAxes_6, app.UIAxes_7, app.UIAxes_8, app.UIAxes_9};
            titles = {"Membrane Voltage","Stimulation Current","Capacitive Current", "Sodium Current", "Potassium Current", ...
"Chlorine Current","Sodium Conductance","Potassium Conductance","Chlorine Conductance"};
            matrices = matrices(selection_idx);
            targets = targets(selection_idx);
            titles = titles(selection_idx);
            plot_propagation(matrices,targets,titles, app.STATUSBARLabel);
            app.STATUSBARLabel.Text = 'Finished';
            app.STATUSBARLabel.BackgroundColor = 'green';
            close all
            set(0,'DefaultFigureVisible','on')            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Membrane Voltage')
            xlabel(app.UIAxes, 'Location(cm)')
            ylabel(app.UIAxes, 'mV')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Stimulation Current')
            xlabel(app.UIAxes_2, 'Location(cm)')
            ylabel(app.UIAxes_2, '{\mu}A/cm^2')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Capacitive Current')
            xlabel(app.UIAxes_3, 'Location(cm)')
            ylabel(app.UIAxes_3, '{\mu}A/cm^2')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Sodium Current')
            xlabel(app.UIAxes_4, 'Location(cm)')
            ylabel(app.UIAxes_4, '{\mu}A/cm^2')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Potassium Current')
            xlabel(app.UIAxes_5, 'Location(cm)')
            ylabel(app.UIAxes_5, '{\mu}A/cm^2')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Chlorine Current')
            xlabel(app.UIAxes_6, 'Location(cm)')
            ylabel(app.UIAxes_6, '{\mu}A/cm^2')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Sodium Conductance')
            xlabel(app.UIAxes_7, 'Location(cm)')
            ylabel(app.UIAxes_7, 'mS/cm^2')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Potassium Conductance')
            xlabel(app.UIAxes_8, 'Location(cm)')
            ylabel(app.UIAxes_8, 'mS/cm^2')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Loss Channel Conductance')
            xlabel(app.UIAxes_9, 'Location(cm)')
            ylabel(app.UIAxes_9, 'mS/cm^2')
            zlabel(app.UIAxes_9, 'Z')
            app.PleaseselectvariablestoplotbelowLabel = uilabel(app.UIFigure);
            app.PleaseselectvariablestoplotbelowLabel.Text = 'Please select variables to plot below.';
            app.Stimulation1Label = uilabel(app.UIFigure);
            app.Stimulation1Label.Text = 'Stimulation 1';
            app.Stimulation2Label = uilabel(app.UIFigure);
            app.Stimulation2Label.Text = 'Stimulation 2';
            app.SimulationTimemsEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationTimemsEditFieldLabel.Text = 'Simulation Time(ms)';
            app.SimulationTimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationTimemsEditField.Value = 12;
            app.MagnitudemuAcm2EditField_2Label = uilabel(app.UIFigure);
            app.MagnitudemuAcm2EditField_2Label.Interpreter = 'latex';
            app.MagnitudemuAcm2EditField_2Label.Text = {'Magnitude'; '($\mu A/cm^2$)'};
            app.MagnitudemuAcm2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MagnitudemuAcm2EditField_2.Value = 100;
            app.DurationmsEditField_2Label = uilabel(app.UIFigure);
            app.DurationmsEditField_2Label.Interpreter = 'latex';
            app.DurationmsEditField_2Label.Text = 'Duration ($ms$)';
            app.DurationmsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DurationmsEditField_2.Value = 8;
            app.SIMULATEButton = uibutton(app.UIFigure, 'state');
            app.SIMULATEButton.ValueChangedFcn = createCallbackFcn(app, @SIMULATEButtonValueChanged, true);
            app.SIMULATEButton.Text = 'SIMULATE';
            app.STATUSBARLabel = uilabel(app.UIFigure);
            app.STATUSBARLabel.Text = 'STATUS BAR';
            app.StarttimemsEditField_2Label = uilabel(app.UIFigure);
            app.StarttimemsEditField_2Label.Interpreter = 'latex';
            app.StarttimemsEditField_2Label.Text = 'Start time ($ms$)';
            app.StarttimemsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.StarttimemsEditField_2.Value = 3;
            app.MagnitudemuAcm2Label = uilabel(app.UIFigure);
            app.MagnitudemuAcm2Label.Interpreter = 'latex';
            app.MagnitudemuAcm2Label.Text = {'Magnitude'; '($\mu A/cm^2$)'};
            app.MagnitudemuAcm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MagnitudemuAcm2EditField.Value = 100;
            app.DurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationmsEditFieldLabel.Interpreter = 'latex';
            app.DurationmsEditFieldLabel.Text = 'Duration ($ms$)';
            app.DurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationmsEditField.Value = 0.15;
            app.StarttimemsEditFieldLabel = uilabel(app.UIFigure);
            app.StarttimemsEditFieldLabel.Interpreter = 'latex';
            app.StarttimemsEditFieldLabel.Text = 'Start time ($ms$)';
            app.StarttimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StarttimemsEditField.Value = 0.5;
            app.LocationrelativetoaxonEditField_2Label = uilabel(app.UIFigure);
            app.LocationrelativetoaxonEditField_2Label.Interpreter = 'latex';
            app.LocationrelativetoaxonEditField_2Label.Text = {'Location'; '(relative to axon)'};
            app.LocationrelativetoaxonEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LocationrelativetoaxonEditField_2.Value = 75;
            app.LocationrelativetoaxonEditFieldLabel = uilabel(app.UIFigure);
            app.LocationrelativetoaxonEditFieldLabel.Interpreter = 'latex';
            app.LocationrelativetoaxonEditFieldLabel.Text = {'Location'; '(relative to axon)'};
            app.LocationrelativetoaxonEditField = uieditfield(app.UIFigure, 'numeric');
            app.LocationrelativetoaxonEditField.Value = 35;
            app.AxonlengthEditFieldLabel = uilabel(app.UIFigure);
            app.AxonlengthEditFieldLabel.Text = 'Axon length';
            app.AxonlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.AxonlengthEditField.Value = 100;
            app.MembraneVoltageCheckBox = uicheckbox(app.UIFigure);
            app.MembraneVoltageCheckBox.Text = 'Membrane Voltage';
            app.MembraneVoltageCheckBox.Value = true;
            app.StimulationcurrentCheckBox = uicheckbox(app.UIFigure);
            app.StimulationcurrentCheckBox.Text = 'Stimulation current';
            app.StimulationcurrentCheckBox.Value = true;
            app.CapacitiveCurrentCheckBox = uicheckbox(app.UIFigure);
            app.CapacitiveCurrentCheckBox.Text = 'Capacitive Current';
            app.CapacitiveCurrentCheckBox.Value = true;
            app.SodiumCurrentCheckBox = uicheckbox(app.UIFigure);
            app.SodiumCurrentCheckBox.Text = 'Sodium Current';
            app.SodiumCurrentCheckBox.Value = true;
            app.PotassiumCurrentCheckBox = uicheckbox(app.UIFigure);
            app.PotassiumCurrentCheckBox.Text = 'Potassium Current';
            app.PotassiumCurrentCheckBox.Value = true;
            app.ChlorineCurrentCheckBox = uicheckbox(app.UIFigure);
            app.ChlorineCurrentCheckBox.Text = 'Chlorine Current';
            app.ChlorineCurrentCheckBox.Value = true;
            app.SodiumConductanceCheckBox = uicheckbox(app.UIFigure);
            app.SodiumConductanceCheckBox.Text = 'Sodium Conductance';
            app.SodiumConductanceCheckBox.Value = true;
            app.PotassiumConductanceCheckBox = uicheckbox(app.UIFigure);
            app.PotassiumConductanceCheckBox.Text = 'Potassium Conductance';
            app.PotassiumConductanceCheckBox.Value = true;
            app.ChlorineConductanceCheckBox = uicheckbox(app.UIFigure);
            app.ChlorineConductanceCheckBox.Text = 'Chlorine Conductance';
            app.ChlorineConductanceCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HH_propagate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end