classdef HH_simulate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DelaybetweenexcitationsEditField  matlab.ui.control.NumericEditField
        DelaybetweenexcitationsEditFieldLabel  matlab.ui.control.Label
        SecondExcitationDurationEditField  matlab.ui.control.NumericEditField
        SecondExcitationDurationEditFieldLabel  matlab.ui.control.Label
        ExcitationAmplitudeinuAEditField  matlab.ui.control.NumericEditField
        ExcitationAmplitudeinuAEditFieldLabel  matlab.ui.control.Label
        SIMULATEButton               matlab.ui.control.Button
        FirstExcitationDurationEditField  matlab.ui.control.NumericEditField
        FirstExcitationDurationEditFieldLabel  matlab.ui.control.Label
        NumberofExcitationsDropDown  matlab.ui.control.DropDown
        NumberofExcitationsDropDownLabel  matlab.ui.control.Label
        UIAxes_13                    matlab.ui.control.UIAxes
        UIAxes_12                    matlab.ui.control.UIAxes
        UIAxes_11                    matlab.ui.control.UIAxes
        UIAxes_10                    matlab.ui.control.UIAxes
        UIAxes_9                     matlab.ui.control.UIAxes
        UIAxes_8                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DelaybetweenexcitationsEditField
        function DelaybetweenexcitationsEditFieldValueChanged(app, event)
            value = app.DelaybetweenexcitationsEditField.Value;
            
        end

        % Button pushed function: SIMULATEButton
        function SIMULATEButtonPushed(app, event)
            [time, V_membrane, I_d, I_C, I_Na, I_K, I_L, g_Na, g_K, g_L] = HHSimulate(app.NumberofExcitationsDropDown.Value,[app.FirstExcitationDurationEditField.Value, app.SecondExcitationDurationEditField.Value],app.DelaybetweenexcitationsEditField.Value,app.ExcitationAmplitudeinuAEditField.Value,0);
            plot(app.UIAxes_2,time,V_membrane,'LineWidth',2,'Color','red')
            ylabel(app.UIAxes_2,'Membrane Voltage(mV)')
            xlabel(app.UIAxes_2,'Time(ms)')
            
            plot(app.UIAxes,time,I_d,'LineWidth',3)
            ylabel(app.UIAxes,'Current({\mu}A)')
            xlabel(app.UIAxes,'Time(ms)')
            
            plot(app.UIAxes_3,time,I_K,'LineWidth',2,'Color','green')
            ylabel(app.UIAxes_3,'Current({\mu}A/cm^2)')
            xlabel(app.UIAxes_3,'Time(ms)') 
            
            plot(app.UIAxes_4,time,I_Na,'LineWidth',2,'Color','green')
            ylabel(app.UIAxes_4,'Current({\mu}A/cm^2)')
            xlabel(app.UIAxes_4,'Time(ms)')            
            
            plot(app.UIAxes_8,time,I_L,'LineWidth',2,'Color','green')
            ylabel(app.UIAxes_8,'Current({\mu}A/cm^2')
            xlabel(app.UIAxes_8,'Time(ms)') 
            
            plot(app.UIAxes_9,time,g_Na,'LineWidth',2,'Color','black')
            ylabel(app.UIAxes_9,'Conductance(mS/cm^2)')
            xlabel(app.UIAxes_9,'Time(ms)') 
            
            plot(app.UIAxes_10,time,g_K,'LineWidth',2,'Color','black')
            ylabel(app.UIAxes_10,'Conductance(mS/cm^2)')
            xlabel(app.UIAxes_10,'Time(ms)') 
            
            plot(app.UIAxes_11,time,g_L,'LineWidth',2,'Color','black')
            ylabel(app.UIAxes_11,'Conductance(mS/cm^2)')
            xlabel(app.UIAxes_11,'Time(ms)') 
            
            plot(app.UIAxes_12,time,I_C,'LineWidth',2,'Color','green')
            ylabel(app.UIAxes_12,'Current({\mu}A/cm^2)')
            xlabel(app.UIAxes_12,'Time(ms)')       
 
            plot(app.UIAxes_13,time,I_d,'LineWidth',2,'Color','blue')
            ylabel(app.UIAxes_13,'Current({\mu}A/cm^2)')
            xlabel(app.UIAxes_13,'Time(ms)')           
 
 
        end

        % Value changed function: ExcitationAmplitudeinuAEditField
        function ExcitationAmplitudeinuAEditFieldValueChanged(app, event)
            value = app.ExcitationAmplitudeinuAEditField.Value;
            
        end

        % Value changed function: FirstExcitationDurationEditField
        function FirstExcitationDurationEditFieldValueChanged(app, event)
            value = app.FirstExcitationDurationEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Excitation Current')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Membrane Voltage')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Potassium Current')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Sodium Current')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Leakage Current')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Sodium Channel Conductance')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, 'Potassium Channel Conductance')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.UIFigure);
            title(app.UIAxes_11, 'Leakage Channel Conductance')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.UIFigure);
            title(app.UIAxes_12, 'Capacitive Current')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_13 = uiaxes(app.UIFigure);
            title(app.UIAxes_13, 'Membrane Current')
            xlabel(app.UIAxes_13, 'X')
            ylabel(app.UIAxes_13, 'Y')
            zlabel(app.UIAxes_13, 'Z')
            app.NumberofExcitationsDropDownLabel = uilabel(app.UIFigure);
            app.NumberofExcitationsDropDownLabel.Text = 'Number of Excitations';
            app.NumberofExcitationsDropDown = uidropdown(app.UIFigure);
            app.NumberofExcitationsDropDown.Items = {'1', '2'};
            app.NumberofExcitationsDropDown.Value = '2';
            app.FirstExcitationDurationEditFieldLabel = uilabel(app.UIFigure);
            app.FirstExcitationDurationEditFieldLabel.Text = 'First Excitation Duration';
            app.FirstExcitationDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstExcitationDurationEditField.ValueChangedFcn = createCallbackFcn(app, @FirstExcitationDurationEditFieldValueChanged, true);
            app.FirstExcitationDurationEditField.Value = 20;
            app.SIMULATEButton = uibutton(app.UIFigure, 'push');
            app.SIMULATEButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULATEButtonPushed, true);
            app.SIMULATEButton.Text = 'SIMULATE';
            app.ExcitationAmplitudeinuAEditFieldLabel = uilabel(app.UIFigure);
            app.ExcitationAmplitudeinuAEditFieldLabel.Text = 'Excitation Amplitude in  uA';
            app.ExcitationAmplitudeinuAEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExcitationAmplitudeinuAEditField.ValueChangedFcn = createCallbackFcn(app, @ExcitationAmplitudeinuAEditFieldValueChanged, true);
            app.ExcitationAmplitudeinuAEditField.Value = 10;
            app.SecondExcitationDurationEditFieldLabel = uilabel(app.UIFigure);
            app.SecondExcitationDurationEditFieldLabel.Text = 'Second Excitation Duration';
            app.SecondExcitationDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondExcitationDurationEditField.Value = 40;
            app.DelaybetweenexcitationsEditFieldLabel = uilabel(app.UIFigure);
            app.DelaybetweenexcitationsEditFieldLabel.Text = 'Delay between excitations';
            app.DelaybetweenexcitationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelaybetweenexcitationsEditField.ValueChangedFcn = createCallbackFcn(app, @DelaybetweenexcitationsEditFieldValueChanged, true);
            app.DelaybetweenexcitationsEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HH_simulate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end